classdef main_system < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        HomeTab_2                   matlab.ui.container.Tab
        ABOUTButton_2               matlab.ui.control.Button
        STARTButton                 matlab.ui.control.Button
        DEVELOPERSButton_2          matlab.ui.control.Button
        Image2                      matlab.ui.control.Image
        DiagnosisTab                matlab.ui.container.Tab
        Button_5                    matlab.ui.control.Button
        iButton_3                   matlab.ui.control.Button
        iButton_2                   matlab.ui.control.Button
        CholesterolStatus           matlab.ui.control.EditField
        AgeSpinnerLabel_17          matlab.ui.control.Label
        BpStatus                    matlab.ui.control.EditField
        AgeSpinnerLabel_16          matlab.ui.control.Label
        iButton                     matlab.ui.control.Button
        DiabetesStatus              matlab.ui.control.EditField
        AgeSpinnerLabel_15          matlab.ui.control.Label
        WeightkgEditField           matlab.ui.control.NumericEditField
        HeightmEditField            matlab.ui.control.NumericEditField
        AgeSpinnerLabel_14          matlab.ui.control.Label
        AgeSpinnerLabel_13          matlab.ui.control.Label
        BmiStatus                   matlab.ui.control.EditField
        DiagnosisResult             matlab.ui.control.TextArea
        AgeSpinnerLabel_12          matlab.ui.control.Label
        ClearAllButton              matlab.ui.control.Button
        RecommendationsButton       matlab.ui.control.Button
        FuzzyLogicGraphButton       matlab.ui.control.Button
        ResultEditField             matlab.ui.control.NumericEditField
        HeartRiskStatusEditField    matlab.ui.control.EditField
        AgeSpinnerLabel_11          matlab.ui.control.Label
        AgeSpinnerLabel_10          matlab.ui.control.Label
        AgeSpinnerLabel_9           matlab.ui.control.Label
        Button_4                    matlab.ui.control.Button
        AgeSpinnerLabel_8           matlab.ui.control.Label
        CholesterolEditField        matlab.ui.control.NumericEditField
        DiabetesmgdlEditField       matlab.ui.control.NumericEditField
        BloodpressuremmHgEditField  matlab.ui.control.NumericEditField
        AgeSpinnerLabel_7           matlab.ui.control.Label
        AgeSpinnerLabel_6           matlab.ui.control.Label
        ResetButton                 matlab.ui.control.Button
        STARTButton_2               matlab.ui.control.Button
        Image5                      matlab.ui.control.Image
        AboutTab                    matlab.ui.container.Tab
        Button_2                    matlab.ui.control.Button
        DIAGNOSISButton_3           matlab.ui.control.Button
        SYSTEMButton                matlab.ui.control.Button
        INFOButton                  matlab.ui.control.Button
        Image4                      matlab.ui.control.Image
        DevelopersTab               matlab.ui.container.Tab
        Button_3                    matlab.ui.control.Button
        Image3                      matlab.ui.control.Image
    end

    
    properties (Access = private)
        About_system_popup % about system popup
        About_diagnosis_popup % about diagnosis popup
        About_info_popup % about info popup
        dc % diabetes chart
        bp % blood pressure chart
        cl % cholesterol chart

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed2(app, event)
            app.TabGroup.SelectedTab = app.DiagnosisTab;
        end

        % Button pushed function: ABOUTButton_2
        function ABOUTButton_2Pushed(app, event)
            app.TabGroup.SelectedTab = app.AboutTab;
        end

        % Button pushed function: DEVELOPERSButton_2
        function DEVELOPERSButton_2Pushed(app, event)
            app.TabGroup.SelectedTab = app.DevelopersTab;
        end

        % Callback function
        function ButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.HomeTab_2;
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.TabGroup.SelectedTab = app.HomeTab_2;
        end

        % Button pushed function: INFOButton
        function INFOButtonPushed(app, event)
            app.About_info_popup=about_infopopup(app);
        end

        % Button pushed function: SYSTEMButton
        function SYSTEMButtonPushed(app, event)
            app.About_system_popup=about_systempopup(app);
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            app.TabGroup.SelectedTab = app.HomeTab_2;
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            app.TabGroup.SelectedTab = app.HomeTab_2;
        end

        % Button pushed function: STARTButton_2
        function STARTButton_2Pushed(app, event)
            height = app.HeightmEditField.Value;
            weight = app.WeightkgEditField.Value;
            
            bmi = weight/(height*height);
            diabetes = app.DiabetesmgdlEditField.Value;
            bpressure = app.BloodpressuremmHgEditField.Value;
            cholesterol = app.CholesterolEditField.Value;
         
            if bmi > 0 && bmi <24.9
                app.DiabetesStatus.Value = 'Normal';

            elseif bmi >= 25 && bmi <29
                app.DiabetesStatus.Value = 'Overweight';

            else
                app.DiabetesStatus.Value = 'Obese';

            end

            if diabetes >= 5 && diabetes <= 7
                app.BmiStatus.Value = 'Good';

            elseif diabetes == 8
                app.BmiStatus.Value = 'Average';

            else
                app.BmiStatus.Value = 'Poor';
            
            end
            
            if bpressure > 0 && bpressure <= 100
                app.BpStatus.Value = 'Low';

            elseif bpressure > 100 && bpressure <=139
                app.BpStatus.Value = 'Medium';

            else 
                app.BpStatus.Value = 'High';

            end

            if cholesterol > 0 && cholesterol <= 182
                app.CholesterolStatus.Value = 'Low';
            
            elseif cholesterol >190 && cholesterol <= 234
                app.CholesterolStatus.Value = 'Medium';

            else
                app.CholesterolStatus.Value = 'High';

            end

            fis = readfis('Latest');
            inputs = [bmi,diabetes,bpressure,cholesterol];
            output = evalfis(fis,inputs);
            app.ResultEditField.Value = output;
            
            if output >= 0.0 && output <= 35
                app.HeartRiskStatusEditField.Value = 'Healthy';
                
            elseif output > 35 && output <= 65
                app.HeartRiskStatusEditField.Value = 'Early-stage';
                
            else
                app.HeartRiskStatusEditField.Value = 'Advanced-stage';   
                
            end
            
            switch app.HeartRiskStatusEditField.Value
                case 'Healthy'
                    app.DiagnosisResult.Value = "Your heart disease risk is healthy. Based on the results of your assessment, we can see that you currently have no known risk factors for heart disease and your heart is functioning normally. It's important to continue to maintain a healthy lifestyle to reduce your risk of developing heart disease in the future.";
                case 'Early-stage'
                    app.DiagnosisResult.Value = "Your heart disease risk is early-stage. Based on the results of your assessment, we can see that you have some risk factors for heart disease. While your heart is currently functioning normally, it's important to take steps to reduce your risk of developing more serious heart problems in the future.";
                case 'Advanced-stage'
                    app.DiagnosisResult.Value = "Your heart disease risk is advanced-stage. Based on the results of your assessment, we can see that you have significant damage to your heart. This puts you at a higher risk of experiencing a heart attack or other complications. It's important to take immediate action to reduce your risk and manage your condition.";
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.HeightmEditField.Value=0;
            app.WeightkgEditField.Value=0;
            app.DiabetesmgdlEditField.Value=5;
            app.BloodpressuremmHgEditField.Value=0;
            app.CholesterolEditField.Value=0;
        end

        % Button pushed function: ClearAllButton
        function ClearAllButtonPushed(app, event)
            app.HeightmEditField.Value=0;
            app.WeightkgEditField.Value=0;
            app.DiabetesmgdlEditField.Value=5;
            app.BloodpressuremmHgEditField.Value=0;
            app.CholesterolEditField.Value=0;
            
            app.BmiStatus.Value='';
            app.DiabetesStatus.Value='';
            app.BpStatus.Value='';
            app.CholesterolStatus.Value='';
            app.ResultEditField.Value=0;
            app.HeartRiskStatusEditField.Value='';
            app.DiagnosisResult.Value='';
        end

        % Button pushed function: FuzzyLogicGraphButton
        function FuzzyLogicGraphButtonPushed(app, event)
            fuzzy("Latest");
        end

        % Button pushed function: RecommendationsButton
        function RecommendationsButtonPushed(app, event)
            switch app.HeartRiskStatusEditField.Value
                case 'Healthy'
                    msgbox(["1. Eating a healthy diet that is rich in fruits, vegetables, whole grains, lean proteins, and healthy fats.";"2. Regular physical activity, aiming for at least 30 minutes of moderate-intensity aerobic activity most days of the week.";"3.	Avoiding smoking and limiting alcohol consumption.";"4.	Maintaining a healthy weight and managing stress through relaxation techniques.";"5. Regular check-ups and screenings, such as blood pressure and cholesterol checks, to monitor heart health and detect any changes over time.";"6. Taking steps to manage any underlying health conditions, such as diabetes or high blood pressure, that may increase the risk of heart disease.";"7. Avoiding environmental factors that can contribute to heart disease, such as air pollution and exposure to secondhand smoke.";"8. Getting enough sleep and maintaining a consistent sleep schedule"],"Tips for Healthy");
                case 'Early-stage'
                    msgbox(["1.	Following a healthy diet that is low in saturated fat, trans fat, and cholesterol and high in fruits, vegetables, and whole grains.";"2. Engaging in regular physical activity, aiming for at least 30 minutes of moderate-intensity aerobic activity most days of the week.";"3. Quitting smoking and avoiding exposure to secondhand smoke.";"4.	Maintaining a healthy weight and managing stress through relaxation techniques.";"5. Regular check-ups and screenings, such as blood pressure and cholesterol checks, to monitor heart health and detect any changes over time.";"6. Taking steps to manage any underlying health conditions, such as diabetes or high blood pressure, that may increase the risk of heart disease.";"7. Taking medications as prescribed by the healthcare professional, if needed.";"8. Monitoring blood glucose levels, if diabetes is an underlying condition.";"10. Considering participation in a cardiac rehabilitation program, if recommended by the healthcare professional."],"Tips for Early-stage");
                case 'Advanced-stage'
                    msgbox(["1.	Following a healthy diet that is low in saturated fat, trans fat, and cholesterol and high in fruits, vegetables, and whole grains.";"2. Engaging in regular physical activity, aiming for at least 30 minutes of moderate-intensity aerobic activity most days of the week.";"3. Quitting smoking and avoiding exposure to secondhand smoke.";"4.	Maintaining a healthy weight and managing stress through relaxation techniques.";"5. Regular check-ups and screenings, such as blood pressure and cholesterol checks, to monitor heart health and detect any changes over time.";"6. Taking steps to manage any underlying health conditions, such as diabetes or high blood pressure, that may increase the risk of heart disease.";"7. Following the guidelines of the National Heart Association of Malaysia to reduce the risk of heart disease.";"8. Taking medications as prescribed by the healthcare professional, including those for blood pressure, cholesterol, and blood thinners if needed.";"9. Monitoring blood glucose levels, if diabetes is an underlying condition.";"10. Considering participation in a cardiac rehabilitation program, if recommended by the healthcare professional.";"11. Considering invasive procedures such as angioplasty or bypass surgery, if recommended by the healthcare professional.";"12. Being aware of the symptoms of heart attack and seeking immediate medical attention if they occur."],"Tips for Advanced-stage");
            end
        end

        % Button pushed function: DIAGNOSISButton_3
        function DIAGNOSISButton_3Pushed(app, event)
            app.About_diagnosis_popup=about_diagnosispopup(app);
        end

        % Button pushed function: iButton
        function iButtonPushed(app, event)
            app.dc=diabetesChart(app);
        end

        % Button pushed function: iButton_3
        function iButton_3Pushed(app, event)
            app.bp=bpChart(app);
        end

        % Button pushed function: iButton_2
        function iButton_2Pushed(app, event)
            app.cl=cholesterolChart(app);
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            msgbox(["HEALTHY";"A healthy individual would typically have normal readings for cholesterol, blood pressure, glucose, and other markers of heart health, and have no history of heart disease or stroke. They may also have a healthy lifestyle, such as regular physical activity, a balanced diet, not smoking and not consuming excessive alcohol.";"";"It's important to note that even though an individual may be considered healthy, it's still important for them to maintain a healthy lifestyle and to be aware of the risk factors for heart disease, in order to reduce their risk of developing heart disease in the future.";"";"EARLY-STAGE";"In the early stage of heart disease, an individual may not show any symptoms, but they have an increased risk of developing more serious heart problems in the future. They may have slightly elevated levels of certain biomarkers associated with heart disease, such as LDL cholesterol, blood pressure, and glucose.";"";"It's important for individuals in the early stages of heart disease to take steps to reduce their risk, such as making lifestyle changes, taking medications as prescribed and following up with their healthcare provider regularly. Early detection and treatment can help prevent or delay the progression of heart disease.";"";"ADVANCED-STAGE";"In the advanced stage of heart disease, an individual may experience symptoms such as chest pain, shortness of breath, fatigue, and difficulty with physical activity. They may have elevated levels of certain biomarkers associated with heart disease, such as LDL cholesterol, blood pressure, and glucose.";"";"It's important for individuals in the advanced stages of heart disease to take immediate action to reduce their risk and manage their condition. This may include lifestyle changes, taking medications as prescribed, following up with their healthcare provider regularly and considering invasive procedures such as angioplasty or bypass surgery. They also need to be aware of the symptoms of heart attack and seek immediate medical attention if they occur.";"";"It's important to note that even with advanced heart disease, it's still possible to improve heart health and reduce the risk of complications with appropriate treatment and lifestyle changes."],"Find Out More");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.HomeTab_2 = uitab(app.TabGroup);
            app.HomeTab_2.Title = 'Home';
            app.Image2 = uiimage(app.HomeTab_2);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'homecardiac2.png');
            app.DEVELOPERSButton_2 = uibutton(app.HomeTab_2, 'push');
            app.DEVELOPERSButton_2.ButtonPushedFcn = createCallbackFcn(app, @DEVELOPERSButton_2Pushed, true);
            app.DEVELOPERSButton_2.Text = 'DEVELOPERS';
            app.STARTButton = uibutton(app.HomeTab_2, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed2, true);
            app.STARTButton.Text = 'START';
            app.ABOUTButton_2 = uibutton(app.HomeTab_2, 'push');
            app.ABOUTButton_2.ButtonPushedFcn = createCallbackFcn(app, @ABOUTButton_2Pushed, true);
            app.ABOUTButton_2.Text = 'ABOUT';
            app.DiagnosisTab = uitab(app.TabGroup);
            app.DiagnosisTab.Title = 'Diagnosis';
            app.Image5 = uiimage(app.DiagnosisTab);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'diagnosecardiac2.png');
            app.STARTButton_2 = uibutton(app.DiagnosisTab, 'push');
            app.STARTButton_2.ButtonPushedFcn = createCallbackFcn(app, @STARTButton_2Pushed, true);
            app.STARTButton_2.Text = 'START';
            app.ResetButton = uibutton(app.DiagnosisTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.AgeSpinnerLabel_6 = uilabel(app.DiagnosisTab);
            app.AgeSpinnerLabel_6.Text = 'Diabetes (5-10):';
            app.AgeSpinnerLabel_7 = uilabel(app.DiagnosisTab);
            app.AgeSpinnerLabel_7.Text = {'Total Cholesterol '; '(mmHg):'};
            app.BloodpressuremmHgEditField = uieditfield(app.DiagnosisTab, 'numeric');
            app.BloodpressuremmHgEditField.Limits = [0 200];
            app.DiabetesmgdlEditField = uieditfield(app.DiagnosisTab, 'numeric');
            app.DiabetesmgdlEditField.Limits = [5 10];
            app.DiabetesmgdlEditField.Value = 5;
            app.CholesterolEditField = uieditfield(app.DiagnosisTab, 'numeric');
            app.CholesterolEditField.Limits = [0 300];
            app.AgeSpinnerLabel_8 = uilabel(app.DiagnosisTab);
            app.AgeSpinnerLabel_8.Text = 'Blood pressure (mmHg):';
            app.Button_4 = uibutton(app.DiagnosisTab, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Icon = fullfile(pathToMLAPP, 'back.png');
            app.Button_4.Text = '';
            app.AgeSpinnerLabel_9 = uilabel(app.DiagnosisTab);
            app.AgeSpinnerLabel_9.Text = 'Diabetes status:';
            app.AgeSpinnerLabel_10 = uilabel(app.DiagnosisTab);
            app.AgeSpinnerLabel_10.Text = 'Disease Risk (Fuzzy Value):';
            app.AgeSpinnerLabel_11 = uilabel(app.DiagnosisTab);
            app.AgeSpinnerLabel_11.Text = 'Heart Risk Status:';
            app.HeartRiskStatusEditField = uieditfield(app.DiagnosisTab, 'text');
            app.ResultEditField = uieditfield(app.DiagnosisTab, 'numeric');
            app.FuzzyLogicGraphButton = uibutton(app.DiagnosisTab, 'push');
            app.FuzzyLogicGraphButton.ButtonPushedFcn = createCallbackFcn(app, @FuzzyLogicGraphButtonPushed, true);
            app.FuzzyLogicGraphButton.Text = 'Fuzzy Logic Graph';
            app.RecommendationsButton = uibutton(app.DiagnosisTab, 'push');
            app.RecommendationsButton.ButtonPushedFcn = createCallbackFcn(app, @RecommendationsButtonPushed, true);
            app.RecommendationsButton.Text = 'Recommendations';
            app.ClearAllButton = uibutton(app.DiagnosisTab, 'push');
            app.ClearAllButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAllButtonPushed, true);
            app.ClearAllButton.Text = 'Clear All';
            app.AgeSpinnerLabel_12 = uilabel(app.DiagnosisTab);
            app.AgeSpinnerLabel_12.Text = 'Diagnosis result:';
            app.DiagnosisResult = uitextarea(app.DiagnosisTab);
            app.BmiStatus = uieditfield(app.DiagnosisTab, 'text');
            app.AgeSpinnerLabel_13 = uilabel(app.DiagnosisTab);
            app.AgeSpinnerLabel_13.Text = 'Height (m):';
            app.AgeSpinnerLabel_14 = uilabel(app.DiagnosisTab);
            app.AgeSpinnerLabel_14.Text = 'Weight (kg):';
            app.HeightmEditField = uieditfield(app.DiagnosisTab, 'numeric');
            app.HeightmEditField.Limits = [0 Inf];
            app.WeightkgEditField = uieditfield(app.DiagnosisTab, 'numeric');
            app.WeightkgEditField.Limits = [0 Inf];
            app.AgeSpinnerLabel_15 = uilabel(app.DiagnosisTab);
            app.AgeSpinnerLabel_15.Text = 'Body Mass Index (BMI):';
            app.DiabetesStatus = uieditfield(app.DiagnosisTab, 'text');
            app.iButton = uibutton(app.DiagnosisTab, 'push');
            app.iButton.ButtonPushedFcn = createCallbackFcn(app, @iButtonPushed, true);
            app.iButton.Text = 'i';
            app.AgeSpinnerLabel_16 = uilabel(app.DiagnosisTab);
            app.AgeSpinnerLabel_16.Text = 'Blood pressure status:';
            app.BpStatus = uieditfield(app.DiagnosisTab, 'text');
            app.AgeSpinnerLabel_17 = uilabel(app.DiagnosisTab);
            app.AgeSpinnerLabel_17.Text = 'Cholesterol status:';
            app.CholesterolStatus = uieditfield(app.DiagnosisTab, 'text');
            app.iButton_2 = uibutton(app.DiagnosisTab, 'push');
            app.iButton_2.ButtonPushedFcn = createCallbackFcn(app, @iButton_2Pushed, true);
            app.iButton_2.Text = 'i';
            app.iButton_3 = uibutton(app.DiagnosisTab, 'push');
            app.iButton_3.ButtonPushedFcn = createCallbackFcn(app, @iButton_3Pushed, true);
            app.iButton_3.Text = 'i';
            app.Button_5 = uibutton(app.DiagnosisTab, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '?';
            app.AboutTab = uitab(app.TabGroup);
            app.AboutTab.Title = 'About';
            app.Image4 = uiimage(app.AboutTab);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'aboutcardiac.png');
            app.INFOButton = uibutton(app.AboutTab, 'push');
            app.INFOButton.ButtonPushedFcn = createCallbackFcn(app, @INFOButtonPushed, true);
            app.INFOButton.Text = 'INFO';
            app.SYSTEMButton = uibutton(app.AboutTab, 'push');
            app.SYSTEMButton.ButtonPushedFcn = createCallbackFcn(app, @SYSTEMButtonPushed, true);
            app.SYSTEMButton.Text = 'SYSTEM';
            app.DIAGNOSISButton_3 = uibutton(app.AboutTab, 'push');
            app.DIAGNOSISButton_3.ButtonPushedFcn = createCallbackFcn(app, @DIAGNOSISButton_3Pushed, true);
            app.DIAGNOSISButton_3.Text = 'DIAGNOSIS';
            app.Button_2 = uibutton(app.AboutTab, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = fullfile(pathToMLAPP, 'back.png');
            app.Button_2.Text = '';
            app.DevelopersTab = uitab(app.TabGroup);
            app.DevelopersTab.Title = 'Developers';
            app.Image3 = uiimage(app.DevelopersTab);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'developerscardiac.png');
            app.Button_3 = uibutton(app.DevelopersTab, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Icon = fullfile(pathToMLAPP, 'back.png');
            app.Button_3.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main_system

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end